classdef AirsoftCalculationTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab_Menu                     matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        Panel                        matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Text_Version_2               matlab.ui.control.Label
        Text_Version                 matlab.ui.control.Label
        Text_Author                  matlab.ui.control.Label
        Text_Title_2                 matlab.ui.control.Label
        Text_Title                   matlab.ui.control.Label
        Tab_Params                   matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        SimulationParametersPanel    matlab.ui.container.Panel
        Spinner_air_density          matlab.ui.control.Spinner
        AngledegLabel_4              matlab.ui.control.Label
        Spinner_gravity              matlab.ui.control.Spinner
        AngledegLabel_3              matlab.ui.control.Label
        Toggle_spin_decay            matlab.ui.control.ToggleSwitch
        SpinDecaySwitchLabel         matlab.ui.control.Label
        Toggle_magnus                matlab.ui.control.ToggleSwitch
        MagnusEffectSwitchLabel      matlab.ui.control.Label
        Toggle_friction              matlab.ui.control.ToggleSwitch
        AirFrictionSwitchLabel       matlab.ui.control.Label
        Toggle_gravity               matlab.ui.control.ToggleSwitch
        GravitySwitchLabel           matlab.ui.control.Label
        Lamp_SpinDecay               matlab.ui.control.Lamp
        Lamp_MagnusEffect            matlab.ui.control.Lamp
        Lamp_AirFriction             matlab.ui.control.Lamp
        Lamp_Gravity                 matlab.ui.control.Lamp
        OutputGaugesPanel            matlab.ui.container.Panel
        Output_velocity              matlab.ui.control.NumericEditField
        ProjectilevelocitymsEditFieldLabel  matlab.ui.control.Label
        Output_Spin                  matlab.ui.control.NumericEditField
        SpinrpmEditFieldLabel        matlab.ui.control.Label
        KineticEnergyJLabel          matlab.ui.control.Label
        Gauge_energy                 matlab.ui.control.LinearGauge
        Gauge_velocity               matlab.ui.control.LinearGauge
        KineticEnergyJLabel_2        matlab.ui.control.Label
        AngleIndicatorLabel          matlab.ui.control.Label
        Gauge_angle                  matlab.ui.control.NinetyDegreeGauge
        CalculationPanel             matlab.ui.container.Panel
        Lamp_Feedback                matlab.ui.control.Lamp
        Button_StartCalculation      matlab.ui.control.Button
        AirSoftParametersPanel       matlab.ui.container.Panel
        Spinner_height               matlab.ui.control.Spinner
        AngledegLabel_2              matlab.ui.control.Label
        Spinner_angle                matlab.ui.control.Spinner
        AngledegLabel                matlab.ui.control.Label
        Knob_hopUp                   matlab.ui.control.Knob
        HopUpLabel                   matlab.ui.control.Label
        BBParametersPanel            matlab.ui.container.Panel
        Toggle_Ekin_v                matlab.ui.control.RockerSwitch
        InputSwitchLabel             matlab.ui.control.Label
        Spinner_velocity             matlab.ui.control.Spinner
        ProjectilevelocitymsLabel    matlab.ui.control.Label
        Spinner_diameter             matlab.ui.control.Spinner
        ProjectileDiametermmLabel    matlab.ui.control.Label
        Spinner_mass                 matlab.ui.control.Spinner
        ProjectileMassgSpinnerLabel  matlab.ui.control.Label
        Spinner_energy               matlab.ui.control.Spinner
        KineticEnergyJSpinnerLabel   matlab.ui.control.Label
        Tab_SimPlots                 matlab.ui.container.Tab
        Plot2D_Trajectory            matlab.ui.control.UIAxes
        Plot2D_Energy                matlab.ui.control.UIAxes
        DTrajectoryTab               matlab.ui.container.Tab
        Plot3D_Trajectory            matlab.ui.control.UIAxes
        Tab_Help                     matlab.ui.container.Tab
    end

    
    properties (Access = public)
        %% Simulation Settings
        Bool_Gravity;           % Is Gravity Enabled in the Simulation?
        Bool_AirFriction;       % Is Air friction Enabled in the Simulation?
        Bool_MagnusEffect;      % Is the Magnus Effect Enabled in the Simulation?
        Bool_SpinDecay;         % Is the Spin of the BB getting slower during the Sim?
        
        %% Projectile Properties
        BB_Mass;                % Mass of the projectile in kg
        BB_Diameter;            % Diameter of the Projectile in m
        BB_Angle_Initial;       % Angle of whicht the Projectile is shot [0-90 deg]
        BB_Height_Initial;      % Height of the Projecile at start in m
        BB_Hop_Up;              % How much Hop-Up [0-100%] (0% => No rotation / 100% => Rolling without slipping)
        BB_Velocity_Initial;    % Inital Muzzle Velocity in m/s
        BB_Spin_Initial;        % Initial spin in RPM
        BB_Ekin_Initial;        % Initial Kinetic Energy in J
        BB_Erot_Initial;        % Initial Rotational Energy in J
        
        %% Simulation Properties
        SIM_Gravity;            % Gravity in m/s^2
        SIM_Air_Density;        % Air Density in kg/m^3
        tspan_end;              % Max Simulation Time
        
        %% Simulated Values
        ODE_t                   % time in s
        ODE_y                   % height in m
        ODE_x                   % distance in m
        ODE_Ekin                % Kinetic Energy in J
        ODE_Epot                % Potential Energy in J
        ODE_Erot                % Rotational Energy in J
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            IO_InputValues(app);
            IO_ToggleFunctions(app);
        end

        % Value changed function: Knob_hopUp
        function Knob_hopUpValueChanged(app, event)
            IO_InputValues(app);
        end

        % Value changing function: Knob_hopUp
        function Knob_hopUpValueChanging(app, event)
            IO_InputValues(app);
        end

        % Value changed function: Spinner_energy
        function Spinner_energyValueChanged(app, event)
            IO_InputValues(app);
        end

        % Value changed function: Spinner_mass
        function Spinner_massValueChanged(app, event)
            IO_InputValues(app);
        end

        % Value changed function: Spinner_diameter
        function Spinner_diameterValueChanged(app, event)
            IO_InputValues(app);
        end

        % Value changed function: Toggle_gravity
        function Toggle_gravityValueChanged(app, event)
            IO_ToggleFunctions(app)
        end

        % Value changed function: Toggle_friction
        function Toggle_frictionValueChanged(app, event)
            IO_ToggleFunctions(app);
        end

        % Value changed function: Toggle_magnus
        function Toggle_magnusValueChanged(app, event)
            IO_ToggleFunctions(app);
        end

        % Value changed function: Toggle_spin_decay
        function Toggle_spin_decayValueChanged(app, event)
            IO_ToggleFunctions(app);
        end

        % Button pushed function: Button_StartCalculation
        function Button_StartCalculationPushed(app, event)
            %Start Calculation
            IO_CalculationButtonPushed(app);
        end

        % Value changed function: Spinner_angle
        function Spinner_angleValueChanged(app, event)
            IO_InputValues(app);
        end

        % Value changing function: Spinner_angle
        function Spinner_angleValueChanging(app, event)
            IO_InputValues(app);
        end

        % Value changed function: Toggle_Ekin_v
        function Toggle_Ekin_vValueChanged(app, event)
            IO_InputValues(app);
        end

        % Value changed function: Spinner_velocity
        function Spinner_velocityValueChanged(app, event)
            IO_InputValues(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Menu = uitab(app.TabGroup);
            app.Tab_Menu.Title = 'Menu';
            app.GridLayout2 = uigridlayout(app.Tab_Menu);
            app.Text_Title = uilabel(app.GridLayout2);
            app.Text_Title.Text = 'ARTEMIS';
            app.Text_Title_2 = uilabel(app.GridLayout2);
            app.Text_Title_2.Text = {'Airsoft Range and Trajectory Evaluation '; 'using MATLAB for Interactive Simulations'};
            app.Text_Author = uilabel(app.GridLayout2);
            app.Text_Author.Text = {'    P. Kruppe, '; '    M. Reichhoff, '; '    M. Goldschmidt '};
            app.Text_Version = uilabel(app.GridLayout2);
            app.Text_Version.Text = '  Version 0.8';
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.Text_Version_2 = uilabel(app.GridLayout3);
            app.Text_Version_2.Text = {'Changelog:'; ''; 'Version 0.6'; ''; '+ Added differential Solver Function'; '+ Improved UI Interactivity'; '+ Added Changelog'; '+ Parameter Tab fully functional'; '+ Added Gauges for Kinetic and Rotational Energy'; '+ Added Event Detection'; '+ All Internal Code is now located inside Functions'; '- Removed Herobrine'; ''; 'Version 0.7'; ''; '+ Revamped UI Layout'; '+ Added Toggle Switch for velocity or Energy as Initial condition'; ''; 'Version 0.8'; ''; '+ Added Energy Plot'};
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'Airsoft_img.png');
            app.Tab_Params = uitab(app.TabGroup);
            app.Tab_Params.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.Tab_Params);
            app.BBParametersPanel = uipanel(app.GridLayout);
            app.BBParametersPanel.Title = 'BB Parameters';
            app.KineticEnergyJSpinnerLabel = uilabel(app.BBParametersPanel);
            app.KineticEnergyJSpinnerLabel.Text = 'Kinetic Energy [J]';
            app.Spinner_energy = uispinner(app.BBParametersPanel);
            app.Spinner_energy.Step = 0.1;
            app.Spinner_energy.Limits = [0 Inf];
            app.Spinner_energy.ValueChangedFcn = createCallbackFcn(app, @Spinner_energyValueChanged, true);
            app.Spinner_energy.Tooltip = {'The Kinetic Energy of the projectile when it leaves the barrel'};
            app.Spinner_energy.Value = 0.2;
            app.ProjectileMassgSpinnerLabel = uilabel(app.BBParametersPanel);
            app.ProjectileMassgSpinnerLabel.Text = 'Projectile Mass [g]';
            app.Spinner_mass = uispinner(app.BBParametersPanel);
            app.Spinner_mass.Step = 0.1;
            app.Spinner_mass.Limits = [0 Inf];
            app.Spinner_mass.ValueChangedFcn = createCallbackFcn(app, @Spinner_massValueChanged, true);
            app.Spinner_mass.Tooltip = {'The mass of the BB Projectile.'; 'Usually between 0.2 - 0.6 grams'};
            app.Spinner_mass.Value = 0.3;
            app.ProjectileDiametermmLabel = uilabel(app.BBParametersPanel);
            app.ProjectileDiametermmLabel.Text = 'Projectile Diameter [mm]';
            app.Spinner_diameter = uispinner(app.BBParametersPanel);
            app.Spinner_diameter.Step = 0.1;
            app.Spinner_diameter.Limits = [0 Inf];
            app.Spinner_diameter.ValueChangedFcn = createCallbackFcn(app, @Spinner_diameterValueChanged, true);
            app.Spinner_diameter.Tooltip = {'The mass of the BB Projectile.'; 'Usually between 0.2 - 0.6 grams'};
            app.Spinner_diameter.Value = 0.3;
            app.ProjectilevelocitymsLabel = uilabel(app.BBParametersPanel);
            app.ProjectilevelocitymsLabel.Text = 'Projectile velocity [m/s]';
            app.Spinner_velocity = uispinner(app.BBParametersPanel);
            app.Spinner_velocity.Step = 0.1;
            app.Spinner_velocity.Limits = [0 Inf];
            app.Spinner_velocity.ValueChangedFcn = createCallbackFcn(app, @Spinner_velocityValueChanged, true);
            app.Spinner_velocity.Tooltip = {'The Kinetic Energy of the projectile when it leaves the barrel'};
            app.Spinner_velocity.Value = 100;
            app.InputSwitchLabel = uilabel(app.BBParametersPanel);
            app.InputSwitchLabel.Text = 'Input';
            app.Toggle_Ekin_v = uiswitch(app.BBParametersPanel, 'rocker');
            app.Toggle_Ekin_v.Items = {'Velocity', 'Kinetic Energy'};
            app.Toggle_Ekin_v.ItemsData = {'v', 'Ekin'};
            app.Toggle_Ekin_v.ValueChangedFcn = createCallbackFcn(app, @Toggle_Ekin_vValueChanged, true);
            app.Toggle_Ekin_v.Value = 'v';
            app.AirSoftParametersPanel = uipanel(app.GridLayout);
            app.AirSoftParametersPanel.Title = 'AirSoft Parameters';
            app.HopUpLabel = uilabel(app.AirSoftParametersPanel);
            app.HopUpLabel.Text = 'Hop Up [%]';
            app.Knob_hopUp = uiknob(app.AirSoftParametersPanel, 'continuous');
            app.Knob_hopUp.ValueChangedFcn = createCallbackFcn(app, @Knob_hopUpValueChanged, true);
            app.Knob_hopUp.ValueChangingFcn = createCallbackFcn(app, @Knob_hopUpValueChanging, true);
            app.Knob_hopUp.Tooltip = {'The Hop-Up in an Airsoft weapon gives a backspin'; 'to the projectile. The Maximum backspin is limited by the velocity of the projectile. 100% Hop-Up means that the projectile spins at the maximum velocity possible.'};
            app.AngledegLabel = uilabel(app.AirSoftParametersPanel);
            app.AngledegLabel.Text = 'Angle [deg]';
            app.Spinner_angle = uispinner(app.AirSoftParametersPanel);
            app.Spinner_angle.ValueChangingFcn = createCallbackFcn(app, @Spinner_angleValueChanging, true);
            app.Spinner_angle.Limits = [0 90];
            app.Spinner_angle.ValueChangedFcn = createCallbackFcn(app, @Spinner_angleValueChanged, true);
            app.Spinner_angle.Tooltip = {'Angle at witch the projectile is shot. 0° is parallel to the ground. 90° is straight vertical.'};
            app.Spinner_angle.Value = 10;
            app.AngledegLabel_2 = uilabel(app.AirSoftParametersPanel);
            app.AngledegLabel_2.Text = 'Height [m]';
            app.Spinner_height = uispinner(app.AirSoftParametersPanel);
            app.Spinner_height.Step = 0.1;
            app.Spinner_height.Limits = [0 Inf];
            app.Spinner_height.Tooltip = {'The Height from which the gun is shot. The ground in this simulaiton is perfectly flat.'};
            app.Spinner_height.Value = 1.5;
            app.CalculationPanel = uipanel(app.GridLayout);
            app.CalculationPanel.Title = 'Calculation';
            app.Button_StartCalculation = uibutton(app.CalculationPanel, 'push');
            app.Button_StartCalculation.ButtonPushedFcn = createCallbackFcn(app, @Button_StartCalculationPushed, true);
            app.Button_StartCalculation.Tooltip = {'Start the Calculation.'; 'This may take a few seconds'};
            app.Button_StartCalculation.Text = 'Calculate';
            app.Lamp_Feedback = uilamp(app.CalculationPanel);
            app.OutputGaugesPanel = uipanel(app.GridLayout);
            app.OutputGaugesPanel.Title = 'Output Gauges';
            app.Gauge_angle = uigauge(app.OutputGaugesPanel, 'ninetydegree');
            app.Gauge_angle.Limits = [0 90];
            app.Gauge_angle.ScaleDirection = 'counterclockwise';
            app.Gauge_angle.Value = 10;
            app.AngleIndicatorLabel = uilabel(app.OutputGaugesPanel);
            app.AngleIndicatorLabel.Text = 'Angle Indicator [°]';
            app.KineticEnergyJLabel_2 = uilabel(app.OutputGaugesPanel);
            app.KineticEnergyJLabel_2.Text = 'Projectile Speed [m/s]';
            app.Gauge_velocity = uigauge(app.OutputGaugesPanel, 'linear');
            app.Gauge_velocity.Limits = [0 1000];
            app.Gauge_velocity.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Gauge_velocity.Value = 2;
            app.Gauge_energy = uigauge(app.OutputGaugesPanel, 'linear');
            app.Gauge_energy.Limits = [0 10];
            app.Gauge_energy.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Gauge_energy.Value = 2;
            app.KineticEnergyJLabel = uilabel(app.OutputGaugesPanel);
            app.KineticEnergyJLabel.Text = 'Kinetic Energy [J]';
            app.SpinrpmEditFieldLabel = uilabel(app.OutputGaugesPanel);
            app.SpinrpmEditFieldLabel.Text = 'Spin [rpm]';
            app.Output_Spin = uieditfield(app.OutputGaugesPanel, 'numeric');
            app.Output_Spin.Editable = 'off';
            app.ProjectilevelocitymsEditFieldLabel = uilabel(app.OutputGaugesPanel);
            app.ProjectilevelocitymsEditFieldLabel.Text = 'Projectile velocity [m/s]';
            app.Output_velocity = uieditfield(app.OutputGaugesPanel, 'numeric');
            app.Output_velocity.Editable = 'off';
            app.Output_velocity.Tooltip = {'Velocity of the Projectile depending on the previous two variables.'};
            app.SimulationParametersPanel = uipanel(app.GridLayout);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.Lamp_Gravity = uilamp(app.SimulationParametersPanel);
            app.Lamp_AirFriction = uilamp(app.SimulationParametersPanel);
            app.Lamp_MagnusEffect = uilamp(app.SimulationParametersPanel);
            app.Lamp_SpinDecay = uilamp(app.SimulationParametersPanel);
            app.GravitySwitchLabel = uilabel(app.SimulationParametersPanel);
            app.GravitySwitchLabel.Text = 'Gravity';
            app.Toggle_gravity = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_gravity.ItemsData = [0 1];
            app.Toggle_gravity.ValueChangedFcn = createCallbackFcn(app, @Toggle_gravityValueChanged, true);
            app.Toggle_gravity.Value = 1;
            app.AirFrictionSwitchLabel = uilabel(app.SimulationParametersPanel);
            app.AirFrictionSwitchLabel.Text = 'Air Friction';
            app.Toggle_friction = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_friction.ItemsData = [0 1];
            app.Toggle_friction.ValueChangedFcn = createCallbackFcn(app, @Toggle_frictionValueChanged, true);
            app.Toggle_friction.Value = 1;
            app.MagnusEffectSwitchLabel = uilabel(app.SimulationParametersPanel);
            app.MagnusEffectSwitchLabel.Text = 'Magnus Effect';
            app.Toggle_magnus = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_magnus.ItemsData = [0 1];
            app.Toggle_magnus.ValueChangedFcn = createCallbackFcn(app, @Toggle_magnusValueChanged, true);
            app.Toggle_magnus.Value = 1;
            app.SpinDecaySwitchLabel = uilabel(app.SimulationParametersPanel);
            app.SpinDecaySwitchLabel.Text = 'Spin Decay';
            app.Toggle_spin_decay = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_spin_decay.ItemsData = [0 1];
            app.Toggle_spin_decay.ValueChangedFcn = createCallbackFcn(app, @Toggle_spin_decayValueChanged, true);
            app.Toggle_spin_decay.Value = 1;
            app.AngledegLabel_3 = uilabel(app.SimulationParametersPanel);
            app.AngledegLabel_3.Text = 'Gravity [m/s²]';
            app.Spinner_gravity = uispinner(app.SimulationParametersPanel);
            app.Spinner_gravity.Step = 0.5;
            app.Spinner_gravity.Limits = [0 Inf];
            app.Spinner_gravity.Tooltip = {'Suface gravity of the Simulation.'; 'Moon: 1.7'; 'Mars: 3.2'};
            app.Spinner_gravity.Value = 9.81;
            app.AngledegLabel_4 = uilabel(app.SimulationParametersPanel);
            app.AngledegLabel_4.Text = 'Air Density [kg/m³]';
            app.Spinner_air_density = uispinner(app.SimulationParametersPanel);
            app.Spinner_air_density.Limits = [0 Inf];
            app.Spinner_air_density.Tooltip = {'Typical Values:'; 'Earth: 1.24'; 'Moon: 0'; 'Venus: 8.23'; 'Mars: 0.54'};
            app.Spinner_air_density.Value = 1.24;
            app.Tab_SimPlots = uitab(app.TabGroup);
            app.Tab_SimPlots.Title = 'Simulation Plot Overview';
            app.Plot2D_Energy = uiaxes(app.Tab_SimPlots);
            title(app.Plot2D_Energy, 'E-t Plot')
            xlabel(app.Plot2D_Energy, 'Time')
            ylabel(app.Plot2D_Energy, 'Energy')
            zlabel(app.Plot2D_Energy, 'Z')
            app.Plot2D_Trajectory = uiaxes(app.Tab_SimPlots);
            title(app.Plot2D_Trajectory, 'y-d Plot')
            xlabel(app.Plot2D_Trajectory, 'Distance from Origin')
            ylabel(app.Plot2D_Trajectory, 'Height')
            zlabel(app.Plot2D_Trajectory, 'Z')
            app.DTrajectoryTab = uitab(app.TabGroup);
            app.DTrajectoryTab.Title = '3D-Trajectory';
            app.Plot3D_Trajectory = uiaxes(app.DTrajectoryTab);
            title(app.Plot3D_Trajectory, '3D Trajectory Plot')
            xlabel(app.Plot3D_Trajectory, 'X')
            ylabel(app.Plot3D_Trajectory, 'Y')
            zlabel(app.Plot3D_Trajectory, 'Z')
            app.Tab_Help = uitab(app.TabGroup);
            app.Tab_Help.Title = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AirsoftCalculationTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end